classdef PhonemeSpeechRecognition < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpeechRecognizer         matlab.ui.Figure
        CaseSelector             matlab.ui.control.DropDown
        ListenButton             matlab.ui.control.Button
        TextArea                 matlab.ui.control.TextArea
        PotentialWordsLabel      matlab.ui.control.Label
        PhonemeGuessesTable      matlab.ui.control.Table
        Execute                  matlab.ui.control.Button
        PoweredByLabel           matlab.ui.control.Label
        MerriamWebsterImage      matlab.ui.control.Image
        CaseDropDownLabel        matlab.ui.control.Label
        WordSpectrographDisplay  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        y % Frequency Data of Word Selected
        fs % Sampling Frequency
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CaseSelector
        function CaseSelectorValueChanged(app, event)
            value = app.CaseSelector.Value;
            switch value
                case "Cat"
                    fileName = "Words\Cat.mp3";
                    [app.y, app.fs] = audioread(fileName);
                    plot(app.WordSpectrographDisplay,app.y);
                case "Dog"
                    fileName = "Words\Dog.mp3";
                    [app.y, app.fs] = audioread(fileName);
                    plot(app.WordSpectrographDisplay,app.y);
            end
        end

        % Button pushed function: ListenButton
        function ListenButtonPushed(app, event)
            sound(app.y, app.fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpeechRecognizer = uifigure('Visible', 'off');
            app.SpeechRecognizer.Name = 'MATLAB App';
            app.WordSpectrographDisplay = uiaxes(app.SpeechRecognizer);
            title(app.WordSpectrographDisplay, {'WordDisplay'; ''})
            xlabel(app.WordSpectrographDisplay, 'Time (seconds)')
            ylabel(app.WordSpectrographDisplay, 'Y')
            zlabel(app.WordSpectrographDisplay, 'Z')
            app.CaseDropDownLabel = uilabel(app.SpeechRecognizer);
            app.CaseDropDownLabel.Text = 'Case';
            app.MerriamWebsterImage = uiimage(app.SpeechRecognizer);
            app.MerriamWebsterImage.ImageSource = 'MerriamWebsterLogo.png';
            app.PoweredByLabel = uilabel(app.SpeechRecognizer);
            app.PoweredByLabel.Text = 'Powered By:';
            app.Execute = uibutton(app.SpeechRecognizer, 'push');
            app.Execute.Text = 'Execute Phoneme Detection';
            app.PhonemeGuessesTable = uitable(app.SpeechRecognizer);
            app.PhonemeGuessesTable.ColumnName = {'Index'; 'Phoneme'; 'Possible Letters'};
            app.PhonemeGuessesTable.RowName = {};
            app.PotentialWordsLabel = uilabel(app.SpeechRecognizer);
            app.PotentialWordsLabel.Text = 'Potential Words:';
            app.TextArea = uitextarea(app.SpeechRecognizer);
            app.ListenButton = uibutton(app.SpeechRecognizer, 'push');
            app.ListenButton.ButtonPushedFcn = createCallbackFcn(app, @ListenButtonPushed, true);
            app.ListenButton.Text = 'Listen';
            app.CaseSelector = uidropdown(app.SpeechRecognizer);
            app.CaseSelector.Items = {'Cat', 'Dog', 'Ghost', 'Apple'};
            app.CaseSelector.ItemsData = {'Cat', 'Dog', 'Ghost', 'Apple'};
            app.CaseSelector.ValueChangedFcn = createCallbackFcn(app, @CaseSelectorValueChanged, true);
            app.CaseSelector.Placeholder = 'CaseSelector';
            app.CaseSelector.Value = 'Cat';
            % Show the figure after all components are created
            app.SpeechRecognizer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhonemeSpeechRecognition

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpeechRecognizer)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpeechRecognizer)
        end
    end
end